classdef knncorregir < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        BorrarButton                   matlab.ui.control.Button
        ClasificarButton               matlab.ui.control.Button
        DistanciaButtonGroup           matlab.ui.container.ButtonGroup
        EuclidianaButton               matlab.ui.control.RadioButton
        ManhattanButton                matlab.ui.control.RadioButton
        MinkowskiButton                matlab.ui.control.RadioButton
        NumerodeclasesSpinnerLabel     matlab.ui.control.Label
        NumerodeclasesSpinner          matlab.ui.control.Spinner
        ColoresdelasclasesButtonGroup  matlab.ui.container.ButtonGroup
        Clase1Button                   matlab.ui.control.RadioButton
        Clase2Button                   matlab.ui.control.RadioButton
        Clase3Button                   matlab.ui.control.RadioButton
        Clase4Button                   matlab.ui.control.RadioButton
        AgregarpuntosparaclasificarButton  matlab.ui.control.StateButton
        KSpinnerLabel                  matlab.ui.control.Label
        KSpinner                       matlab.ui.control.Spinner
    end

    
    properties (Access = public)
        xy =[]; %Matriz que almacena los vectores de los puntos que ya estan clasificados
        clase=[];
        sxy=[] %Matriz que almacena los vectores de los puntos a clasificar
        sclase=[]
        puntos; %struct que guarda los vectores de posicion y la clase
        samples;
        cmap;
    end
    
    methods (Access = public)
        
        function results = KNN(app)
            app.puntos.xy = app.xy;
            app.puntos.clase = app.clase;
            app.samples.xy = app.sxy;
            app.samples.clase = app.sclase;
        
            nsamples = length(app.sclase);
            distancia = zeros(length(app.puntos.clase),nsamples);
            for i=1:nsamples
                for j=1:length(app.puntos.clase)
                    if app.EuclidianaButton.Value == true
                        distancia(j,i) = pdist2(app.samples.xy(i,:), ...
                        app.puntos.xy(j,:),'euclidean');
                    elseif app.ManhattanButton.Value == true
                        distancia(j,i) = sum(abs(app.samples.xy(i,:)- ...
                        app.puntos.xy(j,:)));
                    else
                        distancia(j,i) = pdist2(app.samples.xy(i,:), ...
                        app.puntos.xy(j,:),'minkowski');
                    end
                end
                [~, idx] = mink(distancia,app.KSpinner.Value); %Vecinos k
                for k=1:length(idx(:,i))
                    neighbors_clases(k,i) = app.puntos.clase(idx(k,i));
                end
                moda = mode(neighbors_clases(:,i),'all');
                if moda == 1
                    plot(app.UIAxes,app.samples.xy(i,1),app.samples.xy(i,2),'xr', 'MarkerFaceColor', 'r');
                elseif moda == 2
                    plot(app.UIAxes,app.samples.xy(i,1),app.samples.xy(i,2),'*b', 'MarkerFaceColor', 'b');
                elseif moda == 3
                    plot(app.UIAxes,app.samples.xy(i,1),app.samples.xy(i,2),'dg', 'MarkerFaceColor', 'g');
                else
                    plot(app.UIAxes,app.samples.xy(i,1),app.samples.xy(i,2),'py', 'MarkerFaceColor', 'y');
                end                
            end
            xrange = [-2 2];
            yrange = [-2 2];
            [xx1,xx2] = meshgrid(xrange(1):0.05:xrange(2), yrange(1):0.05:yrange(2));
            image_size = size(xx1);
            gridXY = [xx1(:) xx2(:)];
            for i=1:length(gridXY)
                for j=1:length(app.puntos.xy)
                    dist_mapa(j,i) = pdist2(gridXY(i,:), ...
                        app.puntos.xy(j,:),'euclidean');
                end
                [~, map_index] = mink(dist_mapa, app.KSpinner.Value);
                for k=1:length(map_index(:,i))
                    map_clasificacion(k,i) = app.puntos.clase(map_index(k,i));
                end
                moda_map(i,:) = mode(map_clasificacion(:,i),'all');
            end
            decisionmap = reshape(moda_map, image_size);
            imagesc(app.UIAxes,xrange,yrange,decisionmap);
            colormap(app.UIAxes,app.cmap);
            gscatter(xx1(:), xx2(:), moda_map,"rbgy",".",12,"on",'aaa','bb'); 
            for i=1:length(app.puntos.xy)
                if app.puntos.clase(i) == 1
                    plot(app.UIAxes,app.puntos.xy(i,1),app.puntos.xy(i,2),'xr', 'MarkerFaceColor', 'r');
                elseif app.puntos.clase(i)==2
                    plot(app.UIAxes,app.puntos.xy(i,1),app.puntos.xy(i,2),'*b', 'MarkerFaceColor', 'b');
                elseif app.puntos.clase(i)==3
                    plot(app.UIAxes,app.puntos.xy(i,1),app.puntos.xy(i,2),'dg', 'MarkerFaceColor', 'g');
                else
                    plot(app.UIAxes,app.puntos.xy(i,1),app.puntos.xy(i,2),'py', 'MarkerFaceColor', 'y');
                end
            end
            for i=1:length(app.samples.xy)
                plot(app.UIAxes,app.samples.xy(i,1),app.samples.xy(i,2),'oy', 'MarkerFaceColor', 'k');
            end
        end
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,[-2 2 -2 2]);
        end

        % Button pushed function: BorrarButton
        function BorrarButtonPushed(app, event)
            app.UIAxes.cla;
            app.puntos = [];
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            axis(app.UIAxes,[-2 2 -2 2]);
            temp = app.UIAxes.CurrentPoint; % Returns 2x3 array of points
            loc = [temp(1,1) temp(1,2)]; % Gets the (x,y) coordinates
            hold(app.UIAxes, 'on' )
            if app.AgregarpuntosparaclasificarButton.Value == true
                plot(app.UIAxes,loc(1),loc(2), 'oy', 'MarkerFaceColor', 'k');
                app.sxy = [app.sxy;loc(1),loc(2)];
                app.sclase = [app.sclase, 1];
            else
                if app.Clase1Button.Value == true
                    plot(app.UIAxes,loc(1),loc(2), 'xr', 'MarkerFaceColor', 'r');
                    app.xy = [app.xy;loc(1),loc(2)];
                    app.clase = [app.clase, 1];
                elseif app.Clase2Button.Value == true
                    plot(app.UIAxes,loc(1),loc(2), '*b', 'MarkerFaceColor', 'b');
                    app.xy = [app.xy;loc(1),loc(2)];
                    app.clase = [app.clase,2];
                elseif app.Clase3Button.Value == true
                    plot(app.UIAxes,loc(1),loc(2), 'dg', 'MarkerFaceColor', 'g');
                    app.xy = [app.xy;loc(1),loc(2)];
                    app.clase = [app.clase,3];
                else 
                    plot(app.UIAxes,loc(1),loc(2), 'py', 'MarkerFaceColor', 'y');
                    app.xy = [app.xy;loc(1),loc(2)];
                    app.clase = [app.clase,4];
                end
            end
            
        end

        % Button pushed function: ClasificarButton
        function ClasificarButtonPushed(app, event)
            if app.NumerodeclasesSpinner.Value == 2
                app.cmap = [0.6 0.03 0.03; 0.03 0.03 0.6];
            end
            KNN(app)
        end

        % Value changed function: NumerodeclasesSpinner
        function NumerodeclasesSpinnerValueChanged(app, event)
            value = app.NumerodeclasesSpinner.Value;
            if value == 2
                app.Clase3Button.Visible = false;
                app.cmap = [0.6 0.03 0.03; 0.03 0.03 0.6];
            elseif value == 3
                app.Clase3Button.Visible = true;
                app.Clase4Button.Visible = false;
                app.cmap = [0.6 0.03 0.03; 0.03 0.03 0.6; 0.03 0.6 0.03];
            elseif value == 4
                app.Clase4Button.Visible = true;
                app.cmap = [0.6 0.03 0.03; 0.03 0.03 0.6; 0.03 0.6 0.03; 0.6 0.6 0.03];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'K-NN')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.BorrarButton = uibutton(app.UIFigure, 'push');
            app.BorrarButton.ButtonPushedFcn = createCallbackFcn(app, @BorrarButtonPushed, true);
            app.BorrarButton.Text = 'Borrar';
            app.ClasificarButton = uibutton(app.UIFigure, 'push');
            app.ClasificarButton.ButtonPushedFcn = createCallbackFcn(app, @ClasificarButtonPushed, true);
            app.ClasificarButton.Text = 'Clasificar';
            app.DistanciaButtonGroup = uibuttongroup(app.UIFigure);
            app.DistanciaButtonGroup.Title = 'Distancia';
            app.EuclidianaButton = uiradiobutton(app.DistanciaButtonGroup);
            app.EuclidianaButton.Text = 'Euclidiana';
            app.EuclidianaButton.Value = true;
            app.ManhattanButton = uiradiobutton(app.DistanciaButtonGroup);
            app.ManhattanButton.Text = 'Manhattan';
            app.MinkowskiButton = uiradiobutton(app.DistanciaButtonGroup);
            app.MinkowskiButton.Text = 'Minkowski';
            app.NumerodeclasesSpinnerLabel = uilabel(app.UIFigure);
            app.NumerodeclasesSpinnerLabel.Text = 'Numero de clases';
            app.NumerodeclasesSpinner = uispinner(app.UIFigure);
            app.NumerodeclasesSpinner.Limits = [2 4];
            app.NumerodeclasesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumerodeclasesSpinnerValueChanged, true);
            app.NumerodeclasesSpinner.Value = 2;
            app.ColoresdelasclasesButtonGroup = uibuttongroup(app.UIFigure);
            app.ColoresdelasclasesButtonGroup.Title = 'Colores de las clases';
            app.Clase1Button = uiradiobutton(app.ColoresdelasclasesButtonGroup);
            app.Clase1Button.Text = 'Clase 1';
            app.Clase1Button.Value = true;
            app.Clase2Button = uiradiobutton(app.ColoresdelasclasesButtonGroup);
            app.Clase2Button.Text = 'Clase 2';
            app.Clase3Button = uiradiobutton(app.ColoresdelasclasesButtonGroup);
            app.Clase3Button.Visible = 'off';
            app.Clase3Button.Text = {'Clase 3'; ''};
            app.Clase4Button = uiradiobutton(app.ColoresdelasclasesButtonGroup);
            app.Clase4Button.Visible = 'off';
            app.Clase4Button.Text = 'Clase 4';
            app.AgregarpuntosparaclasificarButton = uibutton(app.UIFigure, 'state');
            app.AgregarpuntosparaclasificarButton.Text = 'Agregar puntos para clasificar';
            app.KSpinnerLabel = uilabel(app.UIFigure);
            app.KSpinnerLabel.Text = 'K';
            app.KSpinner = uispinner(app.UIFigure);
            app.KSpinner.Step = 2;
            app.KSpinner.Limits = [1 9];
            app.KSpinner.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = knncorregir

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end